classdef App1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        UIAxes                matlab.ui.control.UIAxes
        ChargerImageButton    matlab.ui.control.Button
        RotationButton        matlab.ui.control.Button
        InversionButton       matlab.ui.control.Button
        ExtractionBlocButton  matlab.ui.control.Button
        HistogrammeButton     matlab.ui.control.Button
        ProfilIntensitButton  matlab.ui.control.Button
        ScanNiveauButton      matlab.ui.control.Button
        BruitButton           matlab.ui.control.Button
        tailleButton          matlab.ui.control.Button
        Label                 matlab.ui.control.Label
        EditField             matlab.ui.control.EditField
        OccurenceButton       matlab.ui.control.Button
        InterfacedetraitementdimageLabel  matlab.ui.control.Label
    end

    properties (Access = private)
        img
        imgProcessed
        UITextSize  
    end

    methods (Access = private)
    
        function calculOccurrence(app, editNG, resultField)

            ng = editNG.Value;                 %  Lire niveau
            occ = sum(app.img(:) == ng);       %  Calcul occurrence
            resultField.Value = sprintf ('Occurrence = %d', occ);

        end
        
        function extraireBlocBlanc(app, editL1, editL2, editC1, editC2, ax)

            L1 = round(editL1.Value);
            L2 = round(editL2.Value);
            C1 = round(editC1.Value);
            C2 = round(editC2.Value);
        
            [L,C] = size(app.img);
        
            if L1 < 1 || L2 > L || C1 < 1 || C2 > C || L1 >= L2 || C1 >= C2
                uialert(app.UIFigure,'Coordonnées invalides','Erreur')
                return
            end
        
            img_w = uint8(255 * ones(L,C));
            bloc = app.img(L1:L2, C1:C2);
            img_w(L1:L2, C1:C2) = bloc;
        
            imshow (img_w,'Parent',ax);
        
        end
        
        function afficherProfil(app, rbLigne, rbColonne, editNum, ax)

            n = round(editNum.Value);        %  numéro de ligne ou colonne
            [L, C] = size(app.img);
        
            % Vérification
            if rbLigne.Value
                if n < 1 || n > L
                    uialert (app.UIFigure,'Numéro de ligne invalide','Erreur')
                    return
                end
                profil = app.img(n, :);
                x = 1:C;
                xlabelStr =  'Numéro des colonnes';
            else
                if n < 1 || n > C
                    uialert (app.UIFigure,'Numéro de colonne invalide','Erreur')
                    return
                end
                profil = app.img(:, n);
                x = 1:L;
                xlabelStr =  'Numéro des lignes';
            end
        
            %  Affichage
            cla(ax);   % efface ancien graphique
            plot(ax, x, profil , 'b','LineWidth',1.5);
            grid (ax,'on');
            xlabel(ax, xlabelStr);
            ylabel (ax,'Niveau de gris');
            ylim(ax,[0 255]);
            title (ax,'Profil d''intensité');
        
        end
        
        function effectuerScanning(app, editNG, ax)

            % Récupérer le  niveau
            niveau = round(editNG.Value);
        
            %  Vérification
            if niveau < 0 || niveau > 255
                uialert (app.UIFigure,'Le niveau doit être entre 0 et 255','Erreur')
                return
            end
        
            % Scanning : pixels = niveau → 1, les autres → 0
            imgScan = (app.img == niveau);
        
            % Affichage
            imshow (imgScan,'Parent',ax);
            title (ax,sprintf('Scanning du niveau %d',niveau));
        
        end
        
        function appliquerBruit(app, bg, ax, fig)

            % Choix du bruit
            sel = bg.SelectedObject.Tag;
        
            switch  sel
                case 'Gaussian'
                    imgBruit = imnoise(app.img,'gaussian',0,0.01);
                case 'Salt'
                    imgBruit = imnoise(app.img,'salt & pepper',0.02);
                case 'Speckle'
                    imgBruit = imnoise(app.img,'speckle',0.04);
            end 
        
            % Affichage image bruitée
            imshow(imgBruit,'Parent',ax);
            title(ax,'Image bruitée');
        
            % Bouton Filtrer
            btnFiltrer = uibutton(fig,'push','Position',[450 500 120 30],'Text','Filtrer');
            btnFiltrer.ButtonPushedFcn = @(btn,event) ouvrirFenetreFiltrage(app, imgBruit);
        
        end
        
        function ouvrirFenetreFiltrage(app,imgBruit)

            fig1 = uifigure('Name','Choisir Type de Filtre','Position',[500 300 350 200]);

            uilabel(fig1,'Position',[30 120 120 22],'Text','Type de filtre :');

            ddType = uidropdown(fig1,...
                'Position',[150 120 150 22],...
                'Items',{'Passe Bas','Passe Haut'},...
                'Value','Passe Bas');

            btnNext = uibutton(fig1,'push',...
                'Position',[110 60 120 30],...
                'Text','Continuer');

            btnNext.ButtonPushedFcn = @(btn,event) ...
                ouvrirSousFiltre(app,imgBruit,ddType.Value);

        end

        %% ================= FILTRAGE ETAPE 2 =================
        function ouvrirSousFiltre(app,imgBruit,typeFiltre)

            fig2 = uifigure('Name','Choisir Sous-Filtre','Position',[450 200 600 500]);

            ax = uiaxes(fig2,'Position',[50 50 500 320]);
            axis(ax,'image');

            uilabel(fig2,'Position',[30 420 150 22],'Text','Choisir filtre :');

            if strcmp(typeFiltre,'Passe Bas')
                items = {'Moyenneur','Médian'};
            else
                items = {'Robert','Sobel','Laplacien'};
            end

            ddSub = uidropdown(fig2,...
                'Position',[180 420 150 22],...
                'Items',items,...
                'Value',items{1});

            btnApply = uibutton(fig2,'push',...
                'Position',[400 415 120 30],...
                'Text','Appliquer');

            btnApply.ButtonPushedFcn = @(btn,event) ...
                appliquerFiltrageFinal(app,imgBruit,typeFiltre,ddSub.Value,ax);

        end

        %% ================= APPLICATION FILTRE =================
         function appliquerFiltrageFinal(app,imgBruit,typeFiltre,sousFiltre,ax)

            if strcmp(typeFiltre,'Passe Bas')

                if strcmp(sousFiltre,'Moyenneur')
                    h = fspecial('average',[3 3]);
                    imgFiltree = imfilter(imgBruit,h);
                else
                    imgFiltree = medfilt2(imgBruit,[3 3]);
                end

                imshow(imgFiltree,'Parent',ax);
                title(ax,['Passe Bas - ' sousFiltre]);

                % ===== Bouton PSNR =====
                btnPSNR = uibutton(ax.Parent,'push');
                btnPSNR.Position = [400 380 120 30];
                btnPSNR.Text = 'Calculer PSNR';
                btnPSNR.ButtonPushedFcn = @(btn,event) ...
                calculPSNR(app,imgFiltree,ax.Parent);

            else

                if strcmp(sousFiltre,'Robert')
                    hx=[1 0;0 -1];
                    hy=[0 1;-1 0];
                    gx=imfilter(double(imgBruit),hx);
                    gy=imfilter(double(imgBruit),hy);
                    imgFiltree = sqrt(gx.^2 + gy.^2);

                elseif strcmp(sousFiltre,'Sobel')
                    imgFiltree = edge(imgBruit,'sobel');

                else
                    h1=fspecial('laplacian',0.2);
                    imgFiltree = imfilter(imgBruit,h1);
                end

                imshow(uint8(imgFiltree),'Parent',ax);
                title(ax,['Passe Haut - ' sousFiltre]);

            end
        end

        %% ================= CALCUL PSNR =================
        function calculPSNR(app,imgFiltree,figParent)

            psnr_val = psnr(imgFiltree, app.img);

            if psnr_val < 20
                msg = 'Qualité mauvaise';
            elseif psnr_val < 30
                msg = 'Qualité moyenne';
            elseif psnr_val < 40
                msg = 'Qualité bonne';
            else
                msg = 'Qualité très bonne';
            end

    % 🔹 Affichage dans la fenêtre du filtrage
            uialert(figParent,...
                sprintf('PSNR = %.2f dB\n%s',psnr_val,msg),...
                'Résultat PSNR');

        end

    end


    methods (Access = private)

        % Button pushed function: ChargerImageButton
        function ChargerImageButtonPushed(app, event)
            [file,path] = uigetfile({'*.bmp;*.jpg;*.png'});
            
            if isequal(file,0)
                return
            end
            
            app.img = imread(fullfile(path,file));
            
            if size(app.img,3)==3
                app.img = rgb2gray(app.img);
            end
            
            imshow(app.img,'Parent',app.UIAxes)
        end

        % Callback function
        function SobelButtonPushed(app, event)
            if isempty(app.img)
                uialert(app.UIFigure,'Chargez une image d abord','Erreur')
                return
            end
            
            app.imgProcessed = edge(app.img,'sobel');
            
            imshow(app.imgProcessed,'Parent',app.UIAxes2)
        end

        % Button pushed function: RotationButton
        function RotationButtonPushed(app, event)
            
                if isempty(app.img)
                    uialert(app.UIFigure,'Chargez une image d abord','Erreur')
                    return
                end
            
                fig = uifigure('Name','Rotation','Position',[300 300 500 400]);
            
                uilabel(fig,'Position',[50 330 100 22],'Text','Angle :');
            
                angleField = uieditfield(fig,'numeric');
                angleField.Position = [120 330 100 22];
            
                ax = uiaxes(fig);
                ax.Position = [50 50 400 250];
            
                btn = uibutton(fig,'push');
                btn.Position = [250 330 100 30];
                btn.Text = 'Appliquer';
            
                btn.ButtonPushedFcn = @(btn,event) appliquerRotation();
            
                function appliquerRotation()
            
                    angle = angleField.Value;
                    imgRot = imrotate(app.img, angle);
                    imshow(imgRot,'Parent',ax)
            
                end


        end

        % Button pushed function: InversionButton
        function InversionButtonPushed(app, event)
            if isempty(app.img)
                uialert(app.UIFigure,'Chargez une image d abord','Erreur')
                return
            end
            
            % 1️⃣ Nouvelle fenêtre
            fig = uifigure('Name','Inversion','Position',[300 300 500 400]);
        
            % 2️⃣ Axes pour afficher l'image inversée
            ax = uiaxes(fig);
            ax.Position = [50 50 400 300];
        
            % 3️⃣ Inverser l'image
            imgInv = 255 - app.img;
        
            % 4️⃣ Afficher
            imshow(imgInv,'Parent',ax);
        end

        % Callback function
        function tailleButtonPushed(app, event)
            if isempty(app.img)
                uialert(app.UIFigure, 'Chargez une image d abord','Erreur')
                return
             end
        
            [L,C] = size(app.img);             % Obtenir taille
            app.EditField.Value = sprintf('%d*%d', L, C);
        end

        % Button pushed function: OccurenceButton
        function OccurenceButtonPushed(app, event)
            if isempty(app.img)
                uialert(app.UIFigure,'Chargez une image d abord','Erreur')
                return
            end
        
            % Nouvelle fenêtre
            fig = uifigure('Name', 'Occurrence Niveau Gris','Position',[500 300 300 200]);
        
            % Label
            uilabel(fig ,'Position',[20 140 120 22],'Text','Niveau de gris (0-255)');
        
            %  Champ pour entrer le niveau
            editNG = uieditfield (fig,'numeric');
            editNG.Position = [150 140 100 22];
            editNG.Limits = [0 255];
        
            %  Zone résultat
            resultField = uieditfield (fig,'text');
            resultField.Position = [80 50 140 22];
            resultField.Editable  = 'off';
        
            % Bouton calcul
            btn = uibutton (fig,'push');
            btn.Position = [100 100 100 30];
            btn.Text =  'Calculer';
        
            % Callback du bouton calcul
            btn.ButtonPushedFcn = @(btn,event) calculOccurrence(app, editNG, resultField);
        end

        % Button pushed function: ExtractionBlocButton
        function ExtractionBlocButtonPushed(app, event)
            if isempty(app.img)
                uialert (app.UIFigure,'Chargez une image d’abord','Erreur')
                return
            end
        
            % Nouvelle fenêtre plus grande
            fig = uifigure ('Name','Extraction Bloc sur fond blanc','Position',[400 200 600 500]);
        
            % Labels
            uilabel (fig,'Position',[20 420 120 22],'Text','Ligne début');
            uilabel (fig,'Position',[20 390 120 22],'Text','Ligne fin');
            uilabel (fig,'Position',[20 360 120 22],'Text','Colonne début');
            uilabel (fig,'Position',[20 330 120 22],'Text','Colonne fin');
        
            % Champs numériques
            editL1 = uieditfield (fig,'numeric','Position',[150 420 100 22]);
            editL2 = uieditfield (fig,'numeric','Position',[150 390 100 22]);
            editC1 = uieditfield (fig,'numeric','Position',[150 360 100 22]);
            editC2 = uieditfield (fig,'numeric','Position',[150 330 100 22]);
        
            % Bouton extraire
            btn = uibutton (fig,'push');
            btn.Position = [200 280 150 30];
            btn.Text =  'Extraire Bloc';
        
            % Axes pour afficher résultat
            ax = uiaxes(fig);
            ax.Position = [50 20 500 240];   % grand pour l’image
            axis (ax,'image');               % proportions correctes
        
            % Callback du bouton
            btn.ButtonPushedFcn = @(btn,event) extraireBlocBlanc(app, editL1, editL2, editC1, editC2, ax);
            
        end

        % Button pushed function: HistogrammeButton
        function HistogrammeButtonPushed(app, event)
            if isempty(app.img)
                uialert(app.UIFigure,'Chargez une image d’abord','Erreur')
                return
            end
        
            % Nouvelle fenêtre
            fig = uifigure('Name','Histogramme','Position',[400 200 700 500]);
        
            % Axes pour image
            axImage = uiaxes(fig);
            axImage.Position = [50 250 600 220];
            imshow(app.img,'Parent',axImage);
            title(axImage,'Image');
        
            % Axes pour histogramme
            axHist = uiaxes(fig);
            axHist.Position = [50 20 600 200];
        
            % Calcul de l'histogramme
            counts = imhist(app.img);       % retourne un vecteur 256x1
        
            % Affichage avec bar dans l'UIAxes
            bar(axHist, 0:255, counts, 'FaceColor', [0.2 0.6 0.8]);
            xlim(axHist,[0 255]);
            xlabel(axHist,'Niveaux de gris');
            ylabel(axHist,'Nombre de pixels');
            title(axHist,'Histogramme');
            
            
        end

        % Button pushed function: ProfilIntensitButton
        function ProfilIntensitButtonPushed(app, event)
            if isempty(app.img)
                uialert(app.UIFigure,'Chargez une image d’abord','Erreur')
                return
            end
        
            % Nouvelle fenêtre
            fig = uifigure('Name','Profil d''intensité','Position',[400 200 600 500]);
        
            % Radio buttons pour choix Ligne / Colonne
            bg = uibuttongroup(fig,'Position',[20 400 200 60],'Title','Type');
            rbLigne = uiradiobutton(bg,'Text','Ligne','Position',[10 10 60 22],'Value',true);
            rbColonne = uiradiobutton(bg,'Text','Colonne','Position',[80 10 80 22]);
        
            % Label et champ pour numéro
            uilabel(fig,'Position',[20 350 120 22],'Text','Numéro Ligne/Colonne:');
            editNum = uieditfield(fig,'numeric','Position',[150 350 100 22]);
        
            % Bouton afficher
            btn = uibutton(fig,'push','Position',[270 350 120 30],'Text','Afficher');
        
            % UIAxes pour profil
            ax = uiaxes(fig,'Position',[50 50 500 280]);
            xlabel(ax,'Index');
            ylabel(ax,'Niveau de gris');
            ylim(ax,[0 255]);
            grid(ax,'on');
        
            % Callback bouton Afficher
            btn.ButtonPushedFcn = @(btn,event) afficherProfil(app, rbLigne, rbColonne, editNum, ax);
            
        end

        % Button pushed function: ScanNiveauButton
        function ScanNiveauButtonPushed(app, event)
            if isempty(app.img)
                uialert (app.UIFigure,'Chargez une image d’abord','Erreur')
                return
            end
        
            % Nouvelle fenêtre
            fig = uifigure ('Name','Scanning d''un niveau de gris','Position',[400 200 600 500]);
        
            % Label et champ pour le niveau de gris
            uilabel (fig,'Position',[20 420 150 22],'Text','Niveau de gris (0-255) :');
            editNG = uieditfield (fig,'numeric','Position',[180 420 100 22]);
        
            % Bouton scanner
            btn = uibutton (fig,'push','Position',[300 420 120 30],'Text','Scanner');
        
            % Axes pour afficher résultat
            ax = uiaxes (fig,'Position',[50 50 500 350]);
        
            % Callback du bouton
            btn.ButtonPushedFcn = @(btn,event) effectuerScanning(app, editNG, ax);
        end

        % Button pushed function: BruitButton
        function BruitButtonPushed(app, event)
            if isempty(app.img)
                uialert(app.UIFigure,'Chargez une image d’abord','Erreur')
                return
            end
        
            % Fenêtre pour bruit
            fig = uifigure('Name','Ajout de bruit','Position',[400 200 650 550]);
        
            % RadioButton pour choix type de bruit
            bg = uibuttongroup(fig,'Position',[20 480 250 80],'Title','Type de bruit');
            uiradiobutton(bg,'Text','Gaussian','Position',[10 40 100 22],'Value',true,'Tag','Gaussian');
            uiradiobutton(bg,'Text','Salt & Pepper','Position',[10 20 150 22],'Tag','Salt');
            uiradiobutton(bg,'Text','Speckle','Position',[10 0 100 22],'Tag','Speckle');
        
            % Bouton Appliquer
            btnAppliquer = uibutton(fig,'push','Position',[300 500 120 30],'Text','Appliquer');
        
            % Axes pour image bruitée
            ax = uiaxes(fig,'Position',[50 100 550 370]);
            axis(ax,'image');
        
            % Callback bouton Appliquer
            btnAppliquer.ButtonPushedFcn = @(btn,event) appliquerBruit(app, bg, ax, fig);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Image Originale');
            app.ChargerImageButton = uibutton(app.UIFigure, 'push');
            app.ChargerImageButton.ButtonPushedFcn = createCallbackFcn(app, @ChargerImageButtonPushed, true);
            app.ChargerImageButton.Text = 'Charger Image';
            app.RotationButton = uibutton(app.UIFigure, 'push');
            app.RotationButton.ButtonPushedFcn = createCallbackFcn(app, @RotationButtonPushed, true);
            app.RotationButton.Text = 'Rotation';
            app.InversionButton = uibutton(app.UIFigure, 'push');
            app.InversionButton.ButtonPushedFcn = createCallbackFcn(app, @InversionButtonPushed, true);
            app.InversionButton.Text = 'Inversion';
            app.ExtractionBlocButton = uibutton(app.UIFigure, 'push');
            app.ExtractionBlocButton.ButtonPushedFcn = createCallbackFcn(app, @ExtractionBlocButtonPushed, true);
            app.ExtractionBlocButton.Text = 'Extraction Bloc';
            app.HistogrammeButton = uibutton(app.UIFigure, 'push');
            app.HistogrammeButton.ButtonPushedFcn = createCallbackFcn(app, @HistogrammeButtonPushed, true);
            app.HistogrammeButton.Text = 'Histogramme';
            app.ProfilIntensitButton = uibutton(app.UIFigure, 'push');
            app.ProfilIntensitButton.ButtonPushedFcn = createCallbackFcn(app, @ProfilIntensitButtonPushed, true);
            app.ProfilIntensitButton.Text = 'Profil Intensité';
            app.ScanNiveauButton = uibutton(app.UIFigure, 'push');
            app.ScanNiveauButton.ButtonPushedFcn = createCallbackFcn(app, @ScanNiveauButtonPushed, true);
            app.ScanNiveauButton.Text = 'Scan Niveau';
            app.BruitButton = uibutton(app.UIFigure, 'push');
            app.BruitButton.ButtonPushedFcn = createCallbackFcn(app, @BruitButtonPushed, true);
            app.BruitButton.Text = 'Bruit';
            app.tailleButton = uibutton(app.UIFigure, 'push');
            app.tailleButton.ButtonPushedFcn = createCallbackFcn(app, @tailleButtonPushed, true);
            app.tailleButton.Text = 'taille';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.OccurenceButton = uibutton(app.UIFigure, 'push');
            app.OccurenceButton.ButtonPushedFcn = createCallbackFcn(app, @OccurenceButtonPushed, true);
            app.OccurenceButton.Text = 'Occurence';
            app.InterfacedetraitementdimageLabel = uilabel(app.UIFigure);
            app.InterfacedetraitementdimageLabel.Text = 'Interface de traitement d''image';
        end
    end

    methods (Access = public)

        % Construct app
        function app = App1()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end